classdef playback_ec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        FilesListBoxLabel  matlab.ui.control.Label
        FilesListBox       matlab.ui.control.ListBox
        Label              matlab.ui.control.Label
        Slider             matlab.ui.control.Slider
        LOADFILEButton     matlab.ui.control.Button
        Label_2            matlab.ui.control.Label
        Slider_2           matlab.ui.control.Slider
        FrameUL            matlab.ui.control.UIAxes
        PlayButton         matlab.ui.control.Button
        StopButton         matlab.ui.control.Button
        TimeLLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        colorbar_lim  = 0
        row = 5
        matfile  
        filename
        MyTimer = 0
        num_frames
    end
    
%     methods (Access = private)
%         
%         function results = func(app)ddd
%             
%         end
%     end
    
    methods (Access = private)
        
        function results = Display_keogramm(app, row, colormap_lim)
            clims = [0 colormap_lim];
            %app.matfile;
            keogramm = squeeze(app.matfile.pdm_2d_rot_global(:,row,:));
            if(colormap_lim == 0)
                %imagesc(app.UIAxes,evalin('base',['squeeze(pdm_2d_rot_global(:,' int2str(row) ',:))']));
                imagesc(app.UIAxes,keogramm);
            else    
                %imagesc(app.UIAxes,evalin('base',['squeeze(pdm_2d_rot_global(:,' int2str(row) ',:))']), clims);
                imagesc(app.UIAxes,keogramm, clims);
                %imagesc(app.UIAxes,evalin('base',['squeeze(pdm_2d_rot_global(:,' int2str(row) ',:))']), [0 colorbar_lim]);
            end
            
            colorbar(app.UIAxes);        
           
        end
        
        function results = Display_frame(app, frame_num, colormap_lim)
            if(frame_num < 1)
                frame_num = 1;
            elseif(frame_num>app.num_frames)
                frame_num = app.num_frames;
            end
            clims = [0 colormap_lim];
            frame = app.matfile.pdm_2d_rot_global(:,:, frame_num);
            if(colormap_lim == 0)
                imagesc(app.FrameUL,frame);
                %imagesc(app.FrameUR,frame(1:8,9:16));
                %imagesc(app.FrameDL,frame(9:16,1:8));
                %imagesc(app.FrameDR,frame(9:16,9:16));
            else
                imagesc(app.FrameUL,frame, clims);
                %imagesc(app.FrameUR,frame(1:8,9:16), clims);
                %imagesc(app.FrameDL,frame(9:16,1:8), clims);
                %imagesc(app.FrameDR,frame(9:16,9:16), clims);            
            end
            t = app.matfile.unixtime_dbl_global(1,frame_num);
            d = datetime(t*1000,'ConvertFrom','epochtime','TicksPerSecond',1e3,'Format','dd-MMM-yyyy HH:mm:ss.SSS');
            s = datestr(d, 'mmmm dd, yyyy HH:MM:SS.FFF');
            app.TimeLLabel.Text = s;
        end
        
        
        function results = TimerCallback(app, obj, event)
            a = app.UIAxes.XAxis.Limits(1) + round((app.UIAxes.XAxis.Limits(2) - app.UIAxes.XAxis.Limits(1))/100);
            b = app.UIAxes.XAxis.Limits(2) + round((app.UIAxes.XAxis.Limits(2) - app.UIAxes.XAxis.Limits(1))/100);
            if(b<app.num_frames)
                app.UIAxes.XAxis.Limits(1) = a;
                app.UIAxes.XAxis.Limits(2) = b;           
                Display_frame(app, 1+round(app.UIAxes.XAxis.Limits(1)), app.colorbar_lim);
            else
                stop(app.MyTimer);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            docFileInfo = {dir(fullfile('.','*.mat'))};
            app.FilesListBox.Items = {docFileInfo{1, 1}.name};  
            app.MyTimer = timer('Period',0.1,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
        end

        % Button pushed function: LOADFILEButton
        function LOADFILEButtonPushed(app, event)
            display('Matfile is loading...');
            app.matfile = load(app.filename); 
            app.num_frames = numel(app.matfile.pdm_2d_rot_global(1,1,:));
            app.Slider.Limits(2) = max(max(max(app.matfile.pdm_2d_rot_global)));%evalin('base','max(max(max(pdm_2d_rot_global)))');
            app.Slider.Value = app.Slider.Limits(2);
            display('DONE');
            Display_keogramm(app, app.row, 0);
            app.FilesListBox.Enable = 1;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.row = round(event.Value);
            Display_keogramm(app, app.row , app.colorbar_lim);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.colorbar_lim = round(event.Value);
            Display_keogramm(app, app.row, app.colorbar_lim);
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            app.filename = app.FilesListBox.Value;
            app.FilesListBox.Enable = 0;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %X = app.UIAxes.XAxis;
            %app.UIAxes.XAxis.Limits = app.UIAxes.XAxis.Limits + [1000,1000];
            %Display_frame(app, 1+round(app.UIAxes.XAxis.Limits(1)), app.colorbar_lim);
            start(app.MyTimer);
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %app.UIAxes.YAxis.Limits = [1,16];
            %stop(app.MyTimer);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            display('UIFigureWindowButtonUp'); 
            %limits = app.UIAxes.XAxis.Limits;
            Display_frame(app, 1+round(app.UIAxes.XAxis.Limits(1)), app.colorbar_lim)
            %app.UIAxes.YAxis.Limits = [1,16];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.MyTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Keogramm')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.UIAxes.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'};
            app.FilesListBoxLabel = uilabel(app.UIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.LOADFILEButton = uibutton(app.UIFigure, 'push');
            app.LOADFILEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADFILEButtonPushed, true);
            app.LOADFILEButton.Text = 'LOAD FILE';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 16];
            app.Slider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.Slider_2.Value = 1;
            app.FrameUL = uiaxes(app.UIFigure);
            title(app.FrameUL, '')
            xlabel(app.FrameUL, '')
            ylabel(app.FrameUL, '')
            app.FrameUL.XTick = [1 2 3 4 5 6 7 8];
            app.FrameUL.XTickLabel = '';
            app.FrameUL.YTick = [1 2 3 4 5 6 7 8];
            app.FrameUL.YTickLabel = '';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeLLabel = uilabel(app.UIFigure);
            app.TimeLLabel.Text = 'TimeL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playback_ec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end